classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SeleccinTab                     matlab.ui.container.Tab
        CargararchivoButton             matlab.ui.control.Button
        ReproducirButton                matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        TipodefiltroDropDown            matlab.ui.control.DropDown
        TipodefiltroDropDownLabel       matlab.ui.control.Label
        FrecuenciaCentralEditFieldLabel  matlab.ui.control.Label
        FrecuenciaCentralEditField      matlab.ui.control.NumericEditField
        FactordecalidadEditFieldLabel   matlab.ui.control.Label
        FactordecalidadEditField        matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciraudiofiltradoButton   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ExportarButton                  matlab.ui.control.Button
        SalvaraudiofiltradoButton       matlab.ui.control.Button
        NombredearchivoEditField        matlab.ui.control.EditField
        NombredearchivoEditFieldLabel   matlab.ui.control.Label
        SeleccindefiltroLabel           matlab.ui.control.Label
        ParmetrosdelfiltroLabel         matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        ResumeButton                    matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        EfectosTab                      matlab.ui.container.Tab
        RATEKnobLabel                   matlab.ui.control.Label
        RATEKnob                        matlab.ui.control.Knob
        MODFREQKnobLabel                matlab.ui.control.Label
        MODFREQKnob                     matlab.ui.control.Knob
        WIDTHKnobLabel                  matlab.ui.control.Label
        WIDTHKnob                       matlab.ui.control.Knob
        PhaseroFlangerCheckBox          matlab.ui.control.CheckBox
        EcoCheckBox                     matlab.ui.control.CheckBox
        ReverberacinCheckBox            matlab.ui.control.CheckBox
        DistorsinCheckBox               matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        AplicarfiltradoButton           matlab.ui.control.Button
        EfectosLabel                    matlab.ui.control.Label
        RATEKnob_2Label                 matlab.ui.control.Label
        RATEKnob_2                      matlab.ui.control.Knob
        DELAYKnobLabel                  matlab.ui.control.Label
        DELAYKnob                       matlab.ui.control.Knob
        RATEKnob_3Label                 matlab.ui.control.Label
        RATEKnob_3                      matlab.ui.control.Knob
        DELAYKnob_2Label                matlab.ui.control.Label
        DELAYKnob_2                     matlab.ui.control.Knob
        RATEKnob_4Label                 matlab.ui.control.Label
        RATEKnob_4                      matlab.ui.control.Knob
        DELAYKnob_3Label                matlab.ui.control.Label
        DELAYKnob_3                     matlab.ui.control.Knob
        GraficosTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function CargarArchivo(app, event)
            
            clearvars file;
            clearvars x;
            clearvars Fs;
            
            global file;
            global x; 
            global Fs; 
            global N;
      
            file = uigetfile({'*.wav'});   % selecciona un archivo con extensiones específicas
            
            if  file == 0
                app.Label.Text = 'Archivo no seleccionado';
            else
                app.Label.Text = file;
                [x, Fs] = audioread(file);  % obtiene los datos de un archivo *.wav
                N = length(x);
                app.player = audioplayer(x,Fs);
            end
            
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
            value = app.TipodefiltroDropDown.Value;
            if value =='FIR'
                %progra filtro FIR
                    
            else 
         %progra filtro IIR
            end
        end

        % Button pushed function: ReproducirButton
        function play_original(app, event)
            play(app.player);% reproduce el audio original seleccionado
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            global x;
            
            t = linspace(0,1000000000);
            plot(app.UIAxes2,x);
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)
            
            global x_out;                               % aquí va la variable del audio modificado
            global Fs;
            
            out_filename = app.NombredearchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".wav")
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            audiowrite(fullFileName, x_out, Fs)         % guarda el archivo en un formato *.wav
            
        end

        % Button pushed function: ReproduciraudiofiltradoButton
        function play_modified(app, event)
        
            global x_out;   % variable del audio modificado
            global Fs;
          
            soundsc(x_out, Fs); % reproduce el audio original seleccionado
            
        end

        % Value changed function: EcoCheckBox
        function toggle_eco_effect(app, event)
            
            value = app.EcoCheckBox.Value;
            
            global x;
            global x_out;
            global Fs;
            
            delay1 = 0.3;   delay2 = 0.4;   % delays
            R1 = round(delay1*Fs);     % retardo 1
            R2 = round(delay2*Fs);     % retardo 2
            a1 = 0.3;   a2 = 0.1;   % constantes de atenuación
            
            if value
                for n = R2+1:1:length(x)
                    x_out(n) = x(n) + a1*x(n-R1) + a2*x_out(n-R2);
                end
            else
                x_out = x;
            end
            
        end

        % Value changed function: ReverberacinCheckBox
        function toggle_reverberation_effect(app, event)
            
            value = app.ReverberacinCheckBox.Value;
            
            global x;
            global x_out;
            global Fs;
            
            delay = 0.04;   % delay
            R1 = round(delay*Fs);  % retardo 1
            R2 = round(2*R1);      % retardo 2
            R3 = round(0.5*R1);    % retardo 3
            
            a1 = 0.2;   a2 = 0.2;   % atenuaciones
            
            if value
                for n = R2+R3+1:1:length(x)
                    x_out(n) = x(n) + x(n-R2) + a2*x(n-R2-R3) + a1*x_out(n-R1);
                end
            else
                x_out = x;
            end
        end

        % Value changed function: VibratoCheckBox
        function toggle_vibrato_effect(app, event)
            global x;
            global x_out;

            value = app.VibratoCheckBox.Value;
            
            if value
                Rate = app.RATEKnob.Value
                Modfreq = app.MODFREQKnob.Value
                Width = app.WIDTHKnob.Value
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if WIDTH>DELAY 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                MODFREQ = Modfreq/Rate; % modulation frequency in # samples
                LEN=length(x);        % # of samples in WAV-file
                L=2+DELAY+WIDTH*2;    % length of the entire delay  
                Delayline=zeros(L,1); % memory allocation for delay
                x_out=zeros(size(x));     % memory allocation for output vector
                for n=1:(LEN-1)
                   M=MODFREQ;
                   MOD=sin(M*2*pi*n);
                   TAP=1+DELAY+WIDTH*MOD;
                   i=floor(TAP);
                   frac=TAP-i;
                   Delayline=[x(n);Delayline(1:L-1)]; 
                   %---Linear Interpolation-----------------------------
                   x_out(n)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
                end  
                
            else
                x_out = x;
            end
        end

        % Value changed function: PhaseroFlangerCheckBox
        function toggle_phaser_effect(app, event)
            
            value = app.PhaseroFlangerCheckBox.Value;
            
        end

        % Value changed function: DistorsinCheckBox
        function toggle_distortion_effect(app, event)
            
            value = app.DistorsinCheckBox.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);% para el audio original seleccionado
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resume(app.player);% resume el audio original seleccionado
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player); % hace pausa al audio original seleccionado
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeleccinTab = uitab(app.TabGroup);
            app.SeleccinTab.Title = 'Selección';
            app.CargararchivoButton = uibutton(app.SeleccinTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivo, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.ReproducirButton = uibutton(app.SeleccinTab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @play_original, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.Lamp_2 = uilamp(app.SeleccinTab);
            app.Label = uilabel(app.SeleccinTab);
            app.Label.Text = '';
            app.TipodefiltroDropDown = uidropdown(app.SeleccinTab);
            app.TipodefiltroDropDown.Items = {'FIR', 'IIR'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'FIR';
            app.TipodefiltroDropDownLabel = uilabel(app.SeleccinTab);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.FrecuenciaCentralEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciaCentralEditFieldLabel.Text = 'Frecuencia Central';
            app.FrecuenciaCentralEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FactordecalidadEditFieldLabel = uilabel(app.SeleccinTab);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.ReproduciraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudiofiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @play_modified, true);
            app.ReproduciraudiofiltradoButton.Text = 'Reproducir audio filtrado';
            app.PlotButton = uibutton(app.SeleccinTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GainSlider = uislider(app.SeleccinTab);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.SeleccinTab);
            app.GainSliderLabel.Text = 'Gain';
            app.Lamp = uilamp(app.SeleccinTab);
            app.ExportarButton = uibutton(app.SeleccinTab, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.SalvaraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.SalvaraudiofiltradoButton.Text = 'Salvar audio filtrado';
            app.NombredearchivoEditField = uieditfield(app.SeleccinTab, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.SeleccinTab);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.SeleccindefiltroLabel = uilabel(app.SeleccinTab);
            app.SeleccindefiltroLabel.Text = 'Selección de filtro';
            app.ParmetrosdelfiltroLabel = uilabel(app.SeleccinTab);
            app.ParmetrosdelfiltroLabel.Text = 'Parámetros del filtro';
            app.StopButton = uibutton(app.SeleccinTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.SeleccinTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.PauseButton = uibutton(app.SeleccinTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.RATEKnobLabel = uilabel(app.EfectosTab);
            app.RATEKnobLabel.Text = 'RATE';
            app.RATEKnob = uiknob(app.EfectosTab, 'continuous');
            app.RATEKnob.Limits = [0 140000];
            app.RATEKnob.MajorTicks = [];
            app.MODFREQKnobLabel = uilabel(app.EfectosTab);
            app.MODFREQKnobLabel.Text = 'MODFREQ';
            app.MODFREQKnob = uiknob(app.EfectosTab, 'continuous');
            app.MODFREQKnob.Limits = [0 15];
            app.MODFREQKnob.MajorTicks = [];
            app.WIDTHKnobLabel = uilabel(app.EfectosTab);
            app.WIDTHKnobLabel.Text = 'WIDTH';
            app.WIDTHKnob = uiknob(app.EfectosTab, 'continuous');
            app.WIDTHKnob.Limits = [0 0.01];
            app.WIDTHKnob.MajorTicks = [];
            app.PhaseroFlangerCheckBox = uicheckbox(app.EfectosTab);
            app.PhaseroFlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_phaser_effect, true);
            app.PhaseroFlangerCheckBox.Text = 'Phaser o Flanger';
            app.EcoCheckBox = uicheckbox(app.EfectosTab);
            app.EcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect, true);
            app.EcoCheckBox.Text = 'Eco';
            app.ReverberacinCheckBox = uicheckbox(app.EfectosTab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_reverberation_effect, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.DistorsinCheckBox = uicheckbox(app.EfectosTab);
            app.DistorsinCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_distortion_effect, true);
            app.DistorsinCheckBox.Text = 'Distorsión';
            app.VibratoCheckBox = uicheckbox(app.EfectosTab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_vibrato_effect, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.AplicarfiltradoButton = uibutton(app.EfectosTab, 'push');
            app.AplicarfiltradoButton.Text = 'Aplicar filtrado';
            app.EfectosLabel = uilabel(app.EfectosTab);
            app.EfectosLabel.Text = 'Efectos';
            app.RATEKnob_2Label = uilabel(app.EfectosTab);
            app.RATEKnob_2Label.Text = 'RATE';
            app.RATEKnob_2 = uiknob(app.EfectosTab, 'continuous');
            app.RATEKnob_2.Limits = [0 0.01];
            app.RATEKnob_2.MajorTicks = [];
            app.DELAYKnobLabel = uilabel(app.EfectosTab);
            app.DELAYKnobLabel.Text = 'DELAY';
            app.DELAYKnob = uiknob(app.EfectosTab, 'continuous');
            app.DELAYKnob.Limits = [0 0.01];
            app.DELAYKnob.MajorTicks = [];
            app.RATEKnob_3Label = uilabel(app.EfectosTab);
            app.RATEKnob_3Label.Text = 'RATE';
            app.RATEKnob_3 = uiknob(app.EfectosTab, 'continuous');
            app.RATEKnob_3.Limits = [0 0.01];
            app.RATEKnob_3.MajorTickLabels = {'0', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.DELAYKnob_2Label = uilabel(app.EfectosTab);
            app.DELAYKnob_2Label.Text = 'DELAY';
            app.DELAYKnob_2 = uiknob(app.EfectosTab, 'continuous');
            app.DELAYKnob_2.Limits = [0 0.01];
            app.DELAYKnob_2.MajorTickLabels = {'0', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.RATEKnob_4Label = uilabel(app.EfectosTab);
            app.RATEKnob_4Label.Text = 'RATE';
            app.RATEKnob_4 = uiknob(app.EfectosTab, 'continuous');
            app.RATEKnob_4.Limits = [0 0.01];
            app.RATEKnob_4.MajorTickLabels = {'0', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.DELAYKnob_3Label = uilabel(app.EfectosTab);
            app.DELAYKnob_3Label.Text = 'DELAY';
            app.DELAYKnob_3 = uiknob(app.EfectosTab, 'continuous');
            app.DELAYKnob_3.Limits = [0 0.01];
            app.DELAYKnob_3.MajorTickLabels = {'0', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            title(app.UIAxes2, 'Audio original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraficosTab);
            title(app.UIAxes3, 'Audio procesado')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GraficosTab);
            title(app.UIAxes, 'Respuesta en frecuencia del filtro')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end